classdef exam_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam1UIFigure             matlab.ui.Figure
        ServerResponseLabel_2     matlab.ui.control.Label
        PeriodmsEditField         matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel    matlab.ui.control.Label
        NumFlashesEditField       matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel  matlab.ui.control.Label
        FlashButton               matlab.ui.control.Button
        LEDOffButton              matlab.ui.control.Button
        LEDOnButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            sendCommand("led on")
            app.ServerResponseLabel_2.Text = "LED On";
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            sendCommand("led off")
            app.ServerResponseLabel_2.Text = "LED Off";
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            numFlashes = app.NumFlashesEditField.Value;
            period = app.PeriodmsEditField.Value;
            commandstr = sprintf("flash %d %d",numFlashes,period);
            displaystr = sprintf("Flashes = %d PeriodMs = %d",numFlashes,period);
            app.ServerResponseLabel_2.Text = displaystr;
            sendCommand(commandstr)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam1UIFigure = uifigure('Visible', 'off');
            app.Exam1UIFigure.Name = 'Exam 1';
            app.LEDOnButton = uibutton(app.Exam1UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.Exam1UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.FlashButton = uibutton(app.Exam1UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.NumFlashesEditFieldLabel = uilabel(app.Exam1UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.Exam1UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.Exam1UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.Exam1UIFigure, 'numeric');
            app.ServerResponseLabel_2 = uilabel(app.Exam1UIFigure);
            app.ServerResponseLabel_2.Text = 'Server Response';
            % Show the figure after all components are created
            app.Exam1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam1UIFigure)
        end
    end
end