classdef AdeeptArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmUIFigure     matlab.ui.Figure
        SavedPositionsPanel   matlab.ui.container.Panel
        GoTo3Button           matlab.ui.control.Button
        Set3Button            matlab.ui.control.Button
        DisplayPos3           matlab.ui.control.Label
        GoTo2Button           matlab.ui.control.Button
        Set2Button            matlab.ui.control.Button
        DisplayPos2           matlab.ui.control.Label
        GoTo1Button           matlab.ui.control.Button
        Set1Button            matlab.ui.control.Button
        DisplayPos1           matlab.ui.control.Label
        NumericalValuesPanel  matlab.ui.container.Panel
        ResetButton           matlab.ui.control.Button
        GoButton              matlab.ui.control.Button
        Joint3EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel  matlab.ui.control.Label
        Joint2EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel  matlab.ui.control.Label
        Joint1EditField       matlab.ui.control.NumericEditField
        Joint1EditFieldLabel  matlab.ui.control.Label
        JointSlidersPanel     matlab.ui.container.Panel
        Joint3Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0,0,0,0];
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        preset1Angles = [0 0 0]
        preset2Angles = [0 0 0]
        preset3Angles = [0 0 0]
    end
    
    methods (Access = private)

        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
        end

        function updateEditFields(app)
            app.Joint1EditField.Value = round(app.jointAngles(1));
            app.Joint2EditField.Value = round(app.jointAngles(2));
            app.Joint3EditField.Value = round(app.jointAngles(3));
        end
        
        function updateArm(app)
            A1 = create_A_matrix(0,0,0,app.jointAngles(1));
            A2 = create_A_matrix(11*25.4,0,0,app.jointAngles(2));
            A3 = create_A_matrix(0,4*25.4,0,app.jointAngles(3));

            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;

            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'on';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-350 350];

            % Create vertices for all the patches
            makeStlLink("Link0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("Link1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("Link2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("Link3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0 0 0];
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.updateArm()
            app.updateSliders()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.preset1Angles = app.jointAngles;
            app.DisplayPos1.Text = sprintf("%3.0f  %3.0f  %3.0f", app.preset1Angles);
        end

        % Button pushed function: GoTo1Button
        function GoTo1ButtonPushed(app, event)
            app.jointAngles = app.preset1Angles;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.preset2Angles = app.jointAngles;
            app.DisplayPos2.Text = sprintf("%3.0f  %3.0f  %3.0f", app.preset2Angles);
        end

        % Button pushed function: GoTo2Button
        function GoTo2ButtonPushed(app, event)
            app.jointAngles = app.preset2Angles;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.preset3Angles = app.jointAngles;
            app.DisplayPos3.Text = sprintf("%3.0f  %3.0f  %3.0f", app.preset3Angles);
        end

        % Button pushed function: GoTo3Button
        function GoTo3ButtonPushed(app, event)
            app.jointAngles = app.preset3Angles;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmUIFigure.Name = 'Adeept Arm';
            app.axes_arm = uiaxes(app.AdeeptArmUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.JointSlidersPanel = uipanel(app.AdeeptArmUIFigure);
            app.JointSlidersPanel.Title = 'JointSliders';
            app.Joint1SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.JointSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.JointSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.JointSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.NumericalValuesPanel = uipanel(app.AdeeptArmUIFigure);
            app.NumericalValuesPanel.Title = 'NumericalValues';
            app.Joint1EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint1EditFieldLabel.Text = 'Joint1';
            app.Joint1EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint2EditFieldLabel.Text = 'Joint2';
            app.Joint2EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint3EditFieldLabel.Text = 'Joint3';
            app.Joint3EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.GoButton = uibutton(app.NumericalValuesPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ResetButton = uibutton(app.NumericalValuesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SavedPositionsPanel = uipanel(app.AdeeptArmUIFigure);
            app.SavedPositionsPanel.Title = 'SavedPositions';
            app.DisplayPos1 = uilabel(app.SavedPositionsPanel);
            app.DisplayPos1.Text = '0 0 0';
            app.Set1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.GoTo1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.GoTo1Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo1ButtonPushed, true);
            app.GoTo1Button.Text = 'Go To 1';
            app.DisplayPos2 = uilabel(app.SavedPositionsPanel);
            app.DisplayPos2.Text = '0 0 0';
            app.Set2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.GoTo2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.GoTo2Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo2ButtonPushed, true);
            app.GoTo2Button.Text = 'Go To 2';
            app.DisplayPos3 = uilabel(app.SavedPositionsPanel);
            app.DisplayPos3.Text = '0 0 0';
            app.Set3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.GoTo3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.GoTo3Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo3ButtonPushed, true);
            app.GoTo3Button.Text = 'Go To 3';
            % Show the figure after all components are created
            app.AdeeptArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmUIFigure)
        end
    end
end