classdef AdeeptArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmUIFigure     matlab.ui.Figure
        DiscoButton           matlab.ui.control.StateButton
        ColorPanel            matlab.ui.container.Panel
        LinkDropDown          matlab.ui.control.DropDown
        LinkDropDownLabel     matlab.ui.control.Label
        SetColorButton        matlab.ui.control.Button
        ColorPicker           matlab.ui.control.ColorPicker
        ColorPickerLabel      matlab.ui.control.Label
        SavedPositionsPanel   matlab.ui.container.Panel
        GoTo3Button           matlab.ui.control.Button
        Set3Button            matlab.ui.control.Button
        DisplayPos3           matlab.ui.control.Label
        GoTo2Button           matlab.ui.control.Button
        Set2Button            matlab.ui.control.Button
        DisplayPos2           matlab.ui.control.Label
        GoTo1Button           matlab.ui.control.Button
        Set1Button            matlab.ui.control.Button
        DisplayPos1           matlab.ui.control.Label
        NumericalValuesPanel  matlab.ui.container.Panel
        Joint5EditField       matlab.ui.control.NumericEditField
        Joint5EditFieldLabel  matlab.ui.control.Label
        Joint4EditField       matlab.ui.control.NumericEditField
        Joint4EditFieldLabel  matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        GoButton              matlab.ui.control.Button
        Joint3EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel  matlab.ui.control.Label
        Joint2EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel  matlab.ui.control.Label
        Joint1EditField       matlab.ui.control.NumericEditField
        Joint1EditFieldLabel  matlab.ui.control.Label
        JointSlidersPanel     matlab.ui.container.Panel
        Joint5Slider          matlab.ui.control.Slider
        Joint5Label           matlab.ui.control.Label
        Joint4Slider          matlab.ui.control.Slider
        Joint4Label           matlab.ui.control.Label
        Joint3Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0,0,0,0,0];
        link0Patch
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link5Vertices
        def0col
        def1col
        def2col
        def3col
        def4col
        preset1Angles = [0 0 0 0 0]
        preset2Angles = [0 0 0 0 0]
        preset3Angles = [0 0 0 0 0]
    end
    
    methods (Access = private)

        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointAngles(4);
            app.Joint5Slider.Value = app.jointAngles(5);
        end

        function updateEditFields(app)
            app.Joint1EditField.Value = round(app.jointAngles(1));
            app.Joint2EditField.Value = round(app.jointAngles(2));
            app.Joint3EditField.Value = round(app.jointAngles(3));
            app.Joint4EditField.Value = round(app.jointAngles(4));
            app.Joint5EditField.Value = round(app.jointAngles(5));
        end
        
        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0,45,90,app.jointAngles(1));
            A2 = create_A_matrix(40,0,0,app.jointAngles(2));
            A3 = create_A_matrix(0,0,90,app.jointAngles(3));
            A4 = create_A_matrix(0,0,0,0);
            A5 = create_A_matrix(0,35 + app.jointAngles(4),0,app.jointAngles(5));

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            T0_5 = A1 * A2 * A3 * A4 * A5;

            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link5VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-100 100];
            % app.axes_arm.YLim = [-100 100];
            % app.axes_arm.ZLim = [-50 150];

            % Create vertices for all the patches
            app.link0Patch = makeStlLink("Link0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("Link1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("Link2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("Link3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("Link5.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link5Vertices = get(app.link4Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

            app.def0col = app.link0Patch.FaceColor;
            app.def1col = app.link1Patch.FaceColor;
            app.def2col = app.link2Patch.FaceColor;
            app.def3col = app.link3Patch.FaceColor;
            app.def4col = app.link4Patch.FaceColor;

            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(4) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(5) = changingValue;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0 0 0 0 0];
            app.link0Patch.FaceColor = app.def0col;
            app.link1Patch.FaceColor = app.def1col;
            app.link2Patch.FaceColor = app.def2col;
            app.link3Patch.FaceColor = app.def3col;
            app.link4Patch.FaceColor = app.def4col;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.jointAngles(4) = app.Joint4EditField.Value;
            app.jointAngles(5) = app.Joint5EditField.Value;
            app.updateArm()
            app.updateSliders()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.preset1Angles = app.jointAngles;
            app.DisplayPos1.Text = sprintf("%3.0f  %3.0f  %3.0f  %3.0f  %3.0f", app.preset1Angles);
        end

        % Button pushed function: GoTo1Button
        function GoTo1ButtonPushed(app, event)
            app.jointAngles = app.preset1Angles;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.preset2Angles = app.jointAngles;
            app.DisplayPos2.Text = sprintf("%3.0f  %3.0f  %3.0f  %3.0f  %3.0f", app.preset2Angles);
        end

        % Button pushed function: GoTo2Button
        function GoTo2ButtonPushed(app, event)
            app.jointAngles = app.preset2Angles;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.preset3Angles = app.jointAngles;
            app.DisplayPos3.Text = sprintf("%3.0f  %3.0f  %3.0f  %3.0f  %3.0f", app.preset3Angles);
        end

        % Button pushed function: GoTo3Button
        function GoTo3ButtonPushed(app, event)
            app.jointAngles = app.preset3Angles;
            app.updateArm()
            app.updateSliders()
            app.updateEditFields()
        end

        % Button pushed function: SetColorButton
        function SetColorButtonPushed(app, event)
            rgb = app.ColorPicker.Value;
            link = app.LinkDropDown.Value;
            if strcmp(link, '0')
                app.link0Patch.FaceColor = rgb;
            elseif strcmp(link, '1')
                app.link1Patch.FaceColor = rgb;
            elseif strcmp(link, '2')
                app.link2Patch.FaceColor = rgb;
            elseif strcmp(link, '3')
                app.link3Patch.FaceColor = rgb;
            elseif strcmp(link, '4')
                app.link4Patch.FaceColor = rgb;
            end
        end

        % Value changed function: DiscoButton
        function DiscoButtonValueChanged(app, event)
            for i = 0:4
                rgb = [rand(), rand(), rand()];
                fieldName = sprintf('link%dPatch', i);
                app.(fieldName).FaceColor = rgb;
            end

            steps = 25;
            onePos = randi([-180, 180]);
            twoPos = randi([0, 180]);
            threePos = randi([0, 180]);
            fourPos = randi([0, 20]);
            fivePos = randi([-90, 90]);
            theta_start = app.jointAngles;
            theta_end = [onePos, twoPos, threePos, fourPos, fivePos];

            theta_ranges = zeros(steps, 5);
            for j = 1:5
                theta_ranges(:, j) = linspace(theta_start(j), theta_end(j), steps);
            end

            for k = 1:steps
                for j = 1:5
                    theta = theta_ranges(k, j);
                    app.jointAngles(j) = theta;
                end
                app.updateArm();
                app.updateEditFields();
                app.updateSliders();
                drawnow limitrate;  % Ensures GUI stays responsive during loop
                pause(0.02);        % Small delay for smooth animation
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmUIFigure.Name = 'Adeept Arm';
            app.axes_arm = uiaxes(app.AdeeptArmUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.JointSlidersPanel = uipanel(app.AdeeptArmUIFigure);
            app.JointSlidersPanel.Title = 'JointSliders';
            app.Joint1SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.JointSlidersPanel);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.JointSlidersPanel);
            app.Joint2Slider.Limits = [0 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.JointSlidersPanel);
            app.Joint3Slider.Limits = [0 180];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4Label = uilabel(app.JointSlidersPanel);
            app.Joint4Label.Text = 'Joint4';
            app.Joint4Slider = uislider(app.JointSlidersPanel);
            app.Joint4Slider.Limits = [0 20];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5Label = uilabel(app.JointSlidersPanel);
            app.Joint5Label.Text = 'Joint5';
            app.Joint5Slider = uislider(app.JointSlidersPanel);
            app.Joint5Slider.Limits = [-90 90];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.NumericalValuesPanel = uipanel(app.AdeeptArmUIFigure);
            app.NumericalValuesPanel.Title = 'NumericalValues';
            app.Joint1EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint1EditFieldLabel.Text = 'Joint1';
            app.Joint1EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint2EditFieldLabel.Text = 'Joint2';
            app.Joint2EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint3EditFieldLabel.Text = 'Joint3';
            app.Joint3EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.GoButton = uibutton(app.NumericalValuesPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ResetButton = uibutton(app.NumericalValuesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Joint4EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint4EditFieldLabel.Text = 'Joint4';
            app.Joint4EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.Joint5EditFieldLabel = uilabel(app.NumericalValuesPanel);
            app.Joint5EditFieldLabel.Text = 'Joint5';
            app.Joint5EditField = uieditfield(app.NumericalValuesPanel, 'numeric');
            app.SavedPositionsPanel = uipanel(app.AdeeptArmUIFigure);
            app.SavedPositionsPanel.Title = 'SavedPositions';
            app.DisplayPos1 = uilabel(app.SavedPositionsPanel);
            app.DisplayPos1.Text = '0 0 0 0 0';
            app.Set1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.GoTo1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.GoTo1Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo1ButtonPushed, true);
            app.GoTo1Button.Text = 'Go To 1';
            app.DisplayPos2 = uilabel(app.SavedPositionsPanel);
            app.DisplayPos2.Text = '0 0 0 0 0';
            app.Set2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.GoTo2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.GoTo2Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo2ButtonPushed, true);
            app.GoTo2Button.Text = 'Go To 2';
            app.DisplayPos3 = uilabel(app.SavedPositionsPanel);
            app.DisplayPos3.Text = '0 0 0 0 0';
            app.Set3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.GoTo3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.GoTo3Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo3ButtonPushed, true);
            app.GoTo3Button.Text = 'Go To 3';
            app.ColorPanel = uipanel(app.AdeeptArmUIFigure);
            app.ColorPanel.Title = 'Color';
            app.ColorPickerLabel = uilabel(app.ColorPanel);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.ColorPanel);
            app.SetColorButton = uibutton(app.ColorPanel, 'push');
            app.SetColorButton.ButtonPushedFcn = createCallbackFcn(app, @SetColorButtonPushed, true);
            app.SetColorButton.Text = 'Set Color';
            app.LinkDropDownLabel = uilabel(app.ColorPanel);
            app.LinkDropDownLabel.Text = 'Link';
            app.LinkDropDown = uidropdown(app.ColorPanel);
            app.LinkDropDown.Items = {'0', '1', '2', '3', '4'};
            app.LinkDropDown.Value = '0';
            app.DiscoButton = uibutton(app.AdeeptArmUIFigure, 'state');
            app.DiscoButton.ValueChangedFcn = createCallbackFcn(app, @DiscoButtonValueChanged, true);
            app.DiscoButton.Text = 'Disco!!';
            % Show the figure after all components are created
            app.AdeeptArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmUIFigure)
        end
    end
end