classdef Gold_Guesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FeedbackMessage  matlab.ui.control.Label
        Pos3Button       matlab.ui.control.Button
        Pos2Button       matlab.ui.control.Button
        Pos1Button       matlab.ui.control.Button
        Gold             matlab.ui.control.Image
        Leprechaun       matlab.ui.control.Image
        SuccessValue     matlab.ui.control.Label
        AttemptsValue    matlab.ui.control.Label
        SuccessesLabel   matlab.ui.control.Label
        AttemptsLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        attemptCounter = 0;
        successCounter = 0;
        leprechaunPositions
        goldPositions
    end

    methods (Access = private)

        function makeAGuess(app, location)
            %TODO Move leprechaun, move gold
            fprintf("Pressed location %d\n", location);
            app.Leprechaun.Position = app.leprechaunPositions(location, :);

            goldLocation = randi([1,3]);
            app.Gold.Position = app.goldPositions(goldLocation, :);

            app.attemptCounter = app.attemptCounter + 1;            
            if location == goldLocation
                app.successCounter = app.successCounter + 1;
                app.FeedbackMessage.Text = "You found the gold!";
            else
                app.FeedbackMessage.Text = "Unlucky!";
            end
            app.AttemptsValue.Text = string(app.attemptCounter);
            app.SuccessValue.Text = string(app.successCounter);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            fprintf("Ready\n");

            app.leprechaunPositions(1,:) = [121,273,90,89];
            app.leprechaunPositions(2,:) = [286,273,90,89];
            app.leprechaunPositions(3,:) = [462,273,90,89];

            app.goldPositions(1,:) = [121,132,90,89];
            app.goldPositions(2,:) = [286,132,90,89];
            app.goldPositions(3,:) = [462,132,90,89];
        end

        % Button pushed function: Pos1Button
        function Pos1ButtonPushed(app, event)
            app.makeAGuess(1);
        end

        % Button pushed function: Pos2Button
        function Pos2ButtonPushed(app, event)
           app.makeAGuess(2); 
        end

        % Button pushed function: Pos3Button
        function Pos3ButtonPushed(app, event)
            app.makeAGuess(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AttemptsLabel = uilabel(app.UIFigure);
            app.AttemptsLabel.Text = 'Attempts:';
            app.SuccessesLabel = uilabel(app.UIFigure);
            app.SuccessesLabel.Text = 'Successes:';
            app.AttemptsValue = uilabel(app.UIFigure);
            app.AttemptsValue.Text = '0';
            app.SuccessValue = uilabel(app.UIFigure);
            app.SuccessValue.Text = '0';
            app.Leprechaun = uiimage(app.UIFigure);
            app.Leprechaun.ImageSource = fullfile(pathToMLAPP, 'Leprechaun-PNG-Photos.png');
            app.Gold = uiimage(app.UIFigure);
            app.Gold.ImageSource = fullfile(pathToMLAPP, 'Pot.png');
            app.Pos1Button = uibutton(app.UIFigure, 'push');
            app.Pos1Button.ButtonPushedFcn = createCallbackFcn(app, @Pos1ButtonPushed, true);
            app.Pos1Button.Text = '1';
            app.Pos2Button = uibutton(app.UIFigure, 'push');
            app.Pos2Button.ButtonPushedFcn = createCallbackFcn(app, @Pos2ButtonPushed, true);
            app.Pos2Button.Text = '2';
            app.Pos3Button = uibutton(app.UIFigure, 'push');
            app.Pos3Button.ButtonPushedFcn = createCallbackFcn(app, @Pos3ButtonPushed, true);
            app.Pos3Button.Text = '3';
            app.FeedbackMessage = uilabel(app.UIFigure);
            app.FeedbackMessage.Text = 'You found the gold!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gold_Guesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end