classdef Lab5_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab5UIFigure     matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        TiltSlider       matlab.ui.control.Slider
        TiltSliderLabel  matlab.ui.control.Label
        PanSlider        matlab.ui.control.Slider
        PanSliderLabel   matlab.ui.control.Label
        SpeedSlider      matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        ClickintheareabelowthenusekeyboardkeystodriveLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        priorKey = ""
    end

    methods (Access = private)

        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
        end

        function sendMessage(app, messageType, payload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = payload;
            end
            message = jsonencode(messageStruct);
            fprintf("Sending: %s\n", message);
            app.mqClient.write("me435/lopezjj/to_pi", message);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://test.mosquitto.org");
            subscribe(app.mqClient, "me435/lopezjj/#", "Callback", @app.myCallback)
        end

        % Key press function: Lab5UIFigure
        function Lab5UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,app.priorKey)
                return
            end
            app.priorKey = key;
            fprintf("Send the wheel command\n")

            speed = round(app.SpeedSlider.Value);

            if strcmp(key,"uparrow")
                app.sendMessage("set_speeds",[speed,speed,speed,speed])
            end
            if strcmp(key,"downarrow")
                app.sendMessage("set_speeds",[-speed,-speed,-speed,-speed])
            end
            if strcmp(key,"leftarrow")
                app.sendMessage("set_speeds",[-speed,speed,speed,-speed])
            end
            if strcmp(key,"rightarrow")
                app.sendMessage("set_speeds",[speed,-speed,-speed,speed])
            end
            if strcmp(key,"a")
                app.sendMessage("set_speeds",[-speed/2,-speed/2,speed/2,speed/2])
            end
            if strcmp(key,"d")
                app.sendMessage("set_speeds",[speed/2,speed/2,-speed/2,-speed/2])
            end
        end

        % Key release function: Lab5UIFigure
        function Lab5UIFigureKeyRelease(app, event)
            fprintf("Send Stop\n")
            app.priorKey = "";
            app.sendMessage("stop")
        end

        % Value changing function: PanSlider
        function PanSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.sendMessage("pan",changingValue);
        end

        % Value changing function: TiltSlider
        function TiltSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.sendMessage("tilt",changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab5UIFigure = uifigure('Visible', 'off');
            app.Lab5UIFigure.Name = 'Lab 5';
            app.Lab5UIFigure.KeyPressFcn = createCallbackFcn(app, @Lab5UIFigureKeyPress, true);
            app.Lab5UIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab5UIFigureKeyRelease, true);
            app.ClickintheareabelowthenusekeyboardkeystodriveLabel = uilabel(app.Lab5UIFigure);
            app.ClickintheareabelowthenusekeyboardkeystodriveLabel.Text = 'Click in the area below, then use keyboard keys to drive';
            app.SliderLabel = uilabel(app.Lab5UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.SpeedSlider = uislider(app.Lab5UIFigure);
            app.SpeedSlider.Value = 50;
            app.Panel = uipanel(app.Lab5UIFigure);
            app.Panel.Title = 'Panel';
            app.PanSliderLabel = uilabel(app.Panel);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.Panel);
            app.PanSlider.ValueChangingFcn = createCallbackFcn(app, @PanSliderValueChanging, true);
            app.TiltSliderLabel = uilabel(app.Panel);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.Panel);
            app.TiltSlider.ValueChangingFcn = createCallbackFcn(app, @TiltSliderValueChanging, true);
            % Show the figure after all components are created
            app.Lab5UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab5_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab5UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab5UIFigure)
        end
    end
end